classdef Bin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        preuzmiteslikuButton  matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        NovaslikaLabel        matlab.ui.control.Label
        IzaberiteslikuLabel   matlab.ui.control.Label
        Image_2               matlab.ui.control.Image
        VratisenaprethondustranicuButton  matlab.ui.control.Button
        BinarizujButton       matlab.ui.control.Button
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
         LoadedImage 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if isequal(file,0)
                return;
            end
            img = imread(fullfile(path,file));
            app.LoadedImage = img;          
            app.Image.ImageSource = img;  
        end

        % Button pushed function: BinarizujButton
        function BinarizujButtonPushed(app, event)
            if isempty(app.LoadedImage)
        uialert(app.UIFigure,'Prvo učitaj sliku!','Greška');
        return;
    end

      grayImage = im2gray(app.LoadedImage);   % RGB or grayscale
    BW = imbinarize(grayImage);             % binary 2D
    BW = repmat(BW,[1 1 3]);                % 3-channel
    BW = uint8(BW * 255);                   % uint8 0-255
    app.Image_2.ImageSource = BW;           % display in uiimage

        end

        % Button pushed function: VratisenaprethondustranicuButton
        function VratisenaprethondustranicuButtonPushed(app, event)
            filterApp = filterChooserApp();  
        
            filterApp.UIFigure.Visible = 'on';
            delete(app);
        end

        % Button pushed function: preuzmiteslikuButton
        function preuzmiteslikuButtonPushed(app, event)
            img = app.Image_2.ImageSource;
    if isempty(img)
        return;
    end
    
    % Ako je ImageSource string (putanja) — učitaj sliku
    if ischar(img) || isstring(img)
        try
            img = imread(img);
        catch
            return;
        end
    end
    
    % Dijalog za izbor imena i lokacije
    [file, path] = uiputfile({'*.png'; '*.jpg'; '*.bmp'; '*.tif'}, 'Sačuvaj sliku kao');
    if isequal(file, 0) || isequal(path, 0)
        return;  % korisnik je otkazao
    end
    
    fullFileName = fullfile(path, file);
    
    % Konverzija u uint8 ako je potrebno
    if isfloat(img)
        imgToSave = im2uint8(mat2gray(img));
    else
        imgToSave = img;
    end
    
    % Čuvanje slike
    imwrite(imgToSave, fullFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.BinarizujButton = uibutton(app.UIFigure, 'push');
            app.BinarizujButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizujButtonPushed, true);
            app.BinarizujButton.Text = 'Binarizuj';
            app.VratisenaprethondustranicuButton = uibutton(app.UIFigure, 'push');
            app.VratisenaprethondustranicuButton.ButtonPushedFcn = createCallbackFcn(app, @VratisenaprethondustranicuButtonPushed, true);
            app.VratisenaprethondustranicuButton.Text = 'Vrati se na prethondu stranicu';
            app.Image_2 = uiimage(app.UIFigure);
            app.IzaberiteslikuLabel = uilabel(app.UIFigure);
            app.IzaberiteslikuLabel.Text = 'Izaberite sliku';
            app.NovaslikaLabel = uilabel(app.UIFigure);
            app.NovaslikaLabel.Text = 'Nova slika';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '➡️';
            app.preuzmiteslikuButton = uibutton(app.UIFigure, 'push');
            app.preuzmiteslikuButton.ButtonPushedFcn = createCallbackFcn(app, @preuzmiteslikuButtonPushed, true);
            app.preuzmiteslikuButton.Text = 'preuzmite sliku';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end