classdef GaussianApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        preuzmiteslikuButton_2        matlab.ui.control.Button
        preuzmiteslikuButton          matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        VratisenaprethondustranicuButton  matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        DfiltriranjeButton            matlab.ui.control.Button
        BlockMatchingalgoritamButton  matlab.ui.control.Button
        GaussovfilterButton           matlab.ui.control.Button
        IzaberitenekiodfilteraLabel   matlab.ui.control.Label
        OdaberitejainuumaLabel        matlab.ui.control.Label
        SlikasaprimenjenimfilteromLabel  matlab.ui.control.Label
        SlikasaumomLabel              matlab.ui.control.Label
        Image_3                       matlab.ui.control.Image
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        IzaberiteslikuLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GaussovfilterButton
        function GaussovfilterButtonPushed(app, event)
             noisyImage = app.Image_2.ImageSource;  
        
            if isempty(noisyImage)
                return; 
            end
        
            if size(noisyImage, 3) == 3
                filteredR = imgaussfilt(noisyImage(:,:,1), 2);
                filteredG = imgaussfilt(noisyImage(:,:,2), 2);
                filteredB = imgaussfilt(noisyImage(:,:,3), 2);
                filteredImage = cat(3, filteredR, filteredG, filteredB);
            else
                filteredImage = imgaussfilt(noisyImage, 2);
            end
        
           % Display the result in Image_3
            app.Image_3.ImageSource = filteredImage;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if isequal(file,0)
                return; 
            end
            img = imread(fullfile(path, file));
            
           % Set the selected image as the original
            app.Image.ImageSource = img;
        end

        % Button pushed function: BlockMatchingalgoritamButton
        function BlockMatchingalgoritamButtonPushed(app, event)
            originalImage = app.Image.ImageSource;   % originalna
            noisyImage    = app.Image_2.ImageSource; % šumovita
        
            if isempty(originalImage) || isempty(noisyImage)
                return;
            end
        
            if size(originalImage,3) == 3 && size(noisyImage,3) == 3
                blockSize = 16;
                searchRange = 8;
                filteredImage = zeros(size(noisyImage), 'like', noisyImage);
        
                for channel = 1:3
                    ref = originalImage(:,:,channel);
                    noisy = noisyImage(:,:,channel);
                    [h, w] = size(ref);
        
                    for i = 1:blockSize:h-blockSize+1
                        for j = 1:blockSize:w-blockSize+1
                            refBlock = ref(i:i+blockSize-1, j:j+blockSize-1);
                            minSAD = inf;
                            bestMatch = [0,0];
        
                            for m = -searchRange:searchRange
                                for n = -searchRange:searchRange
                                    row = i+m; col = j+n;
                                    if row>0 && col>0 && row+blockSize-1<=h && col+blockSize-1<=w
                                        noisyBlock = noisy(row:row+blockSize-1, col:col+blockSize-1);
                                        SAD = sum(abs(refBlock(:)-noisyBlock(:)));
                                        if SAD < minSAD
                                            minSAD = SAD;
                                            bestMatch = [m,n];
                                        end
                                    end
                                end
                            end
        
                            bestRow = i+bestMatch(1);
                            bestCol = j+bestMatch(2);
                            filteredImage(i:i+blockSize-1,j:j+blockSize-1,channel) = ...
                                noisy(bestRow:bestRow+blockSize-1,bestCol:bestCol+blockSize-1);
                        end
                    end
                end
        
                app.Image_3.ImageSource = filteredImage;
            else
                uialert(app.UIFigure, 'Slike moraju biti RGB!', 'Greška');
            end
        end

        % Button pushed function: DfiltriranjeButton
        function DfiltriranjeButtonPushed(app, event)
             
           % Load the noisy image from Image_2
            noisyImage = app.Image_2.ImageSource;
        
            if isempty(noisyImage)
                return;
            end
        
            % Convert to double if it isn’t already
            if ~isfloat(noisyImage)
                noisyNorm = im2double(noisyImage);
            else
                noisyNorm = noisyImage;
            end
        
           % Create an empty image for the result
            filteredImage = zeros(size(noisyNorm));
        
           % Apply Wiener filter per channel
            for c = 1:size(noisyNorm,3)
                filteredImage(:,:,c) = wiener2(noisyNorm(:,:,c), [5 5]);
            end
        
            % Convert back to uint8
            filteredImage = im2uint8(min(max(filteredImage,0),1));
        
           % Display the result in Image_3
            app.Image_3.ImageSource = filteredImage;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
                 % Load the slider value
            noiseLevel = event.Value;  
        
            % Load the original image
            originalImage = app.Image.ImageSource;
        
            if isempty(originalImage)
                return; 
            end
        
            % Image normalization
            if ~isfloat(originalImage)
                imgDouble = im2double(originalImage);
            else
                imgDouble = originalImage;
            end
        
            %  Gaussian (varijansa = (noiseLevel/255)^2)
            noisyImage = imnoise(imgDouble, 'gaussian', 0, (noiseLevel/255)^2);
        
            % Display the noisy image only in Image_2
            app.Image_2.ImageSource = im2uint8(noisyImage);
        
            % Do not touch Image_3, the filter will use Image_2
            
        end

        % Button pushed function: VratisenaprethondustranicuButton
        function VratisenaprethondustranicuButtonPushed(app, event)
            filterApp = filterChooserApp();  
            filterApp.UIFigure.Visible = 'on';
            delete(app);
        end

        % Button pushed function: preuzmiteslikuButton
        function preuzmiteslikuButtonPushed(app, event)
           img = app.Image_3.ImageSource;
    if isempty(img)
        return;
    end
    
    % Ako je ImageSource string (putanja) — učitaj sliku
    if ischar(img) || isstring(img)
        try
            img = imread(img);
        catch
            return;
        end
    end
    
    % Dijalog za izbor imena i lokacije
    [file, path] = uiputfile({'*.png'; '*.jpg'; '*.bmp'; '*.tif'}, 'Sačuvaj sliku kao');
    if isequal(file, 0) || isequal(path, 0)
        return;  % korisnik je otkazao
    end
    
    fullFileName = fullfile(path, file);
    
    % Konverzija u uint8 ako je potrebno
    if isfloat(img)
        imgToSave = im2uint8(mat2gray(img));
    else
        imgToSave = img;
    end
    
    % Čuvanje slike
    imwrite(imgToSave, fullFileName);
        end

        % Button pushed function: preuzmiteslikuButton_2
        function preuzmiteslikuButton_2Pushed(app, event)
            img = app.Image_2.ImageSource;
            if isempty(img)
                return;
            end

            % Ako je ImageSource string (putanja) — učitaj sliku
            if ischar(img) || isstring(img)
                try
                    img = imread(img);
                catch
                    return;
                end
            end

            % Dijalog za izbor imena i lokacije
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.bmp'; '*.tif'}, ...
                                     'Sačuvaj šumovitu sliku kao');
            if isequal(file, 0) || isequal(path, 0)
                return;  % korisnik je otkazao
            end

            fullFileName = fullfile(path, file);

            % Konverzija u uint8 ako je potrebno
            if isfloat(img)
                imgToSave = im2uint8(mat2gray(img));
            else
                imgToSave = img;
            end

            % Čuvanje slike
            imwrite(imgToSave, fullFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IzaberiteslikuLabel = uilabel(app.UIFigure);
            app.IzaberiteslikuLabel.Text = 'Izaberite sliku';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.SlikasaumomLabel = uilabel(app.UIFigure);
            app.SlikasaumomLabel.Text = 'Slika sa šumom';
            app.SlikasaprimenjenimfilteromLabel = uilabel(app.UIFigure);
            app.SlikasaprimenjenimfilteromLabel.Text = 'Slika sa primenjenim filterom';
            app.OdaberitejainuumaLabel = uilabel(app.UIFigure);
            app.OdaberitejainuumaLabel.Text = 'Odaberite jačinu šuma';
            app.IzaberitenekiodfilteraLabel = uilabel(app.UIFigure);
            app.IzaberitenekiodfilteraLabel.Text = 'Izaberite neki od filtera';
            app.GaussovfilterButton = uibutton(app.UIFigure, 'push');
            app.GaussovfilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussovfilterButtonPushed, true);
            app.GaussovfilterButton.Text = 'Gaussov filter';
            app.BlockMatchingalgoritamButton = uibutton(app.UIFigure, 'push');
            app.BlockMatchingalgoritamButton.ButtonPushedFcn = createCallbackFcn(app, @BlockMatchingalgoritamButtonPushed, true);
            app.BlockMatchingalgoritamButton.Text = 'Block-Matching algoritam';
            app.DfiltriranjeButton = uibutton(app.UIFigure, 'push');
            app.DfiltriranjeButton.ButtonPushedFcn = createCallbackFcn(app, @DfiltriranjeButtonPushed, true);
            app.DfiltriranjeButton.Text = '3D filtriranje';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.VratisenaprethondustranicuButton = uibutton(app.UIFigure, 'push');
            app.VratisenaprethondustranicuButton.ButtonPushedFcn = createCallbackFcn(app, @VratisenaprethondustranicuButtonPushed, true);
            app.VratisenaprethondustranicuButton.Text = 'Vrati se na prethondu stranicu';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '➡️';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '➡️';
            app.preuzmiteslikuButton = uibutton(app.UIFigure, 'push');
            app.preuzmiteslikuButton.ButtonPushedFcn = createCallbackFcn(app, @preuzmiteslikuButtonPushed, true);
            app.preuzmiteslikuButton.Text = 'preuzmite sliku';
            app.preuzmiteslikuButton_2 = uibutton(app.UIFigure, 'push');
            app.preuzmiteslikuButton_2.ButtonPushedFcn = createCallbackFcn(app, @preuzmiteslikuButton_2Pushed, true);
            app.preuzmiteslikuButton_2.Text = 'preuzmite sliku';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaussianApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end