classdef HarrisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        preuzmiteslikuButton     matlab.ui.control.Button
        VratisenaprethondustranicuButton  matlab.ui.control.Button
        Threshold0Label          matlab.ui.control.Label
        PodesitepragSlider       matlab.ui.control.Slider
        PodesitepragSliderLabel  matlab.ui.control.Label
        IzaberiteslikuLabel      matlab.ui.control.Label
        PokrenialgloritamButton  matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LoadedImage
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'});
            if isequal(file,0)
                return;
            end
            img = imread(fullfile(path, file));

            % Save the image in a property and display it in a UIImage
            app.LoadedImage = img;
            app.Image.ImageSource = img;
        end

        % Callback function
        function SliderzapodeavanjepragaValueChanged(app, event)
           
          
        end

        % Button pushed function: PokrenialgloritamButton
        function PokrenialgloritamButtonPushed(app, event)
            
            if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Nema učitane slike!', 'Greška');
                return;
            end
        
            img = app.LoadedImage;
        
            % Convert to grayscale
            if size(img,3) == 3
                grayImg = rgb2gray(img);
            else
                grayImg = img;
            end
            grayImg = im2double(grayImg);
        
           % Get the slider value
            minQuality = app.PodesitepragSlider.Value;
            app.Threshold0Label.Text = sprintf('Threshold: %.2f', minQuality);
        
            maxPoints = 200; 
            numPoints = max(5, round(minQuality * maxPoints));
        
            corners = corner(grayImg, 'Harris', numPoints);
        
            % Clear the UIAxes and display the result
            cla(app.UIAxes);
            imshow(img, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, corners(:,1), corners(:,2), 'r*', 'MarkerSize', 5);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, sprintf('Harris uglovi (%d tačaka)', numPoints));
        end

        % Value changed function: PodesitepragSlider
        function PodesitepragSliderValueChanged(app, event)
            currentValue = app.PodesitepragSlider.Value;
            app.Threshold0Label.Text = sprintf('Threshold: %.2f', currentValue);
        end

        % Button pushed function: VratisenaprethondustranicuButton
        function VratisenaprethondustranicuButtonPushed(app, event)
            filterApp = filterChooserApp();  
        
            % Show FilterChooserApp
            filterApp.UIFigure.Visible = 'on';
        
            delete(app);
        end

        % Button pushed function: preuzmiteslikuButton
        function preuzmiteslikuButtonPushed(app, event)
             % Dijalog za izbor imena i lokacije
    [file, path] = uiputfile({'*.png'; '*.jpg'; '*.bmp'; '*.tif'}, 'Sačuvaj sliku kao');
    if isequal(file, 0) || isequal(path, 0)
        return;  % korisnik otkazao
    end
    
    fullFileName = fullfile(path, file);
    
    % Sačuvaj sadržaj UIAxes kao sliku
    try
        exportgraphics(app.UIAxes, fullFileName);
    catch
        % Ako exportgraphics nije podržan, koristi getframe kao alternativu
        frame = getframe(app.UIAxes);
        imwrite(frame.cdata, fullFileName);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.PokrenialgloritamButton = uibutton(app.UIFigure, 'push');
            app.PokrenialgloritamButton.ButtonPushedFcn = createCallbackFcn(app, @PokrenialgloritamButtonPushed, true);
            app.PokrenialgloritamButton.Text = 'Pokreni algloritam';
            app.IzaberiteslikuLabel = uilabel(app.UIFigure);
            app.IzaberiteslikuLabel.Text = 'Izaberite sliku';
            app.PodesitepragSliderLabel = uilabel(app.UIFigure);
            app.PodesitepragSliderLabel.Text = 'Podesite prag';
            app.PodesitepragSlider = uislider(app.UIFigure);
            app.PodesitepragSlider.Limits = [0 1];
            app.PodesitepragSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.PodesitepragSlider.ValueChangedFcn = createCallbackFcn(app, @PodesitepragSliderValueChanged, true);
            app.Threshold0Label = uilabel(app.UIFigure);
            app.Threshold0Label.Text = 'Threshold: 0';
            app.VratisenaprethondustranicuButton = uibutton(app.UIFigure, 'push');
            app.VratisenaprethondustranicuButton.ButtonPushedFcn = createCallbackFcn(app, @VratisenaprethondustranicuButtonPushed, true);
            app.VratisenaprethondustranicuButton.Text = 'Vrati se na prethondu stranicu';
            app.preuzmiteslikuButton = uibutton(app.UIFigure, 'push');
            app.preuzmiteslikuButton.ButtonPushedFcn = createCallbackFcn(app, @preuzmiteslikuButtonPushed, true);
            app.preuzmiteslikuButton.Text = 'preuzmite sliku';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarrisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end